classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Dimension2Slider            matlab.ui.control.Slider
        Dimension2SliderLabel       matlab.ui.control.Label
        Dimension1Slider            matlab.ui.control.Slider
        Dimension1SliderLabel       matlab.ui.control.Label
        AreaEditField               matlab.ui.control.NumericEditField
        AreaEditFieldLabel          matlab.ui.control.Label
        ShapeCreatorLabel           matlab.ui.control.Label
        ColorButtonGroup            matlab.ui.container.ButtonGroup
        BlackButton_2               matlab.ui.control.RadioButton
        YellowButton                matlab.ui.control.RadioButton
        BlueButton                  matlab.ui.control.RadioButton
        GreenButton                 matlab.ui.control.RadioButton
        RedButton                   matlab.ui.control.RadioButton
        Dimension2EditField         matlab.ui.control.NumericEditField
        Dimension2EditFieldLabel    matlab.ui.control.Label
        Dimension1EditField         matlab.ui.control.NumericEditField
        Dimension1EditFieldLabel    matlab.ui.control.Label
        ShapeButtonGroup            matlab.ui.container.ButtonGroup
        SquareButton_2              matlab.ui.control.RadioButton
        EqualaterialTriangleButton  matlab.ui.control.RadioButton
        TriangleButton              matlab.ui.control.RadioButton
        CircleButton                matlab.ui.control.RadioButton
        RectangleButton             matlab.ui.control.RadioButton
        DrawButton                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        color = 'red';
        dim1 = 1;
        dim2 = 1;
        shapeChoice = 1;
    end
    
    methods (Access = private)
        
        function results = getShapeChoice(app)
            %Find shape choice
            if (app.RectangleButton.Value == 1)
                app.shapeChoice = 1;
            elseif (app.CircleButton.Value == 1)
                app.shapeChoice = 2;
            elseif (app.TriangleButton.Value == 1)
                app.shapeChoice = 3;
            elseif (app.EqualaterialTriangleButton.Value == 1)
                app.shapeChoice = 4;
            else
                app.shapeChoice = 5;
            end

            results = app.shapeChoice;
        end
        
        function upDateAreaFeild(app)
            app.getShapeChoice();
            
            %Create Shape Obj and update area
            switch app.shapeChoice
                case 1 % Rectangle
                    rect = Rectangle(app.dim1, app.dim2);
                    rect = rect.setColor(app.color);
                    app.AreaEditField.Value = rect.CalcArea();
                case 2 % Circle
                    circle = Circle(app.dim1);
                    circle = circle.setColor(app.color);
                    app.AreaEditField.Value = circle.CalcArea();
                case 3 % Tringle
                    triangle = Triangle(app.dim1, app.dim2);
                    triangle = triangle.setColor(app.color);
                    app.AreaEditField.Value = triangle.CalcArea();
                case 4 % EquilateralTriangle
                    eTriangle = EquilateralTriangle(app.dim1);
                    eTriangle = eTriangle.setColor(app.color);
                    app.AreaEditField.Value = eTriangle.CalcArea();
                case 5 % Square
                    square = Square(app.dim1);
                    square = square.setColor(app.color);
                    app.AreaEditField.Value = square.CalcArea();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            %Create object and draw on plot

            %Find shape choice
            app.getShapeChoice();

            %Find color choice
            if (app.RedButton.Value == 1)
                app.color = 'red';
            elseif (app.GreenButton.Value == 1)
                app.color = 'green';
            elseif (app.BlueButton.Value == 1)
                app.color = 'blue';
            elseif (app.YellowButton.Value == 1)
                app.color = 'yellow';
            else
                app.color = 'black';
            end
    
            shapeName = "";
            x = [];
            y = [];

            maxX = 0;
            maxY = 0;

            %Create Shape Obj
            switch app.shapeChoice
                case 1 % Rectangle
                    rect = Rectangle(app.dim1, app.dim2);
                    shapeName = rect.Name;

                    x = [0, 0, rect.Length, rect.Length];
                    y = [0, rect.Width, rect.Width, 0];
                    
                    maxX = rect.Length;
                    maxY = rect.Width;
                case 2 % Circle
                    circle = Circle(app.dim1);
                    shapeName = circle.Name;

                    cla(app.UIAxes);
                    rectangle(app.UIAxes, ...
                        'Position', [0,0,circle.radius, circle.radius], ...
                        'FaceColor', app.color, ...
                        'Curvature',[1 1]);

                    maxX = circle.radius;
                    maxY = circle.radius;
                case 3 % Tringle
                    triangle = Triangle(app.dim1, app.dim2);
                    shapeName = triangle.Name;

                    x = [0, triangle.Base/2, triangle.Base];
                    y = [0, triangle.Hieght, 0];

                    maxX = triangle.Base;
                    maxY = triangle.Hieght;
                case 4 % EquilateralTriangle
                    eTriangle = EquilateralTriangle(app.dim1);
                    shapeName = eTriangle.Name;

                    x = [0, eTriangle.Base/2, eTriangle.Base];
                    y = [0, eTriangle.Hieght, 0];

                    maxX = eTriangle.Base;
                    maxY = eTriangle.Hieght;
                case 5 % Square
                    square = Square(app.dim1);
                    shapeName = square.Name;

                    x = [0, 0, square.Length, square.Length];
                    y = [0, square.Width, square.Width, 0];

                    maxX = square.Length;
                    maxY = square.Width;
            end

            if (app.shapeChoice ~= 2)
                plot(app.UIAxes,x,y);
                area(app.UIAxes,x,y, 'FaceColor', app.color);
            end
            title(app.UIAxes, shapeName);
            axis (app.UIAxes, ...
                [-3, maxX+3, -3, maxY+3]);

            app.upDateAreaFeild();
        end

        % Value changed function: Dimension2Slider
        function Dimension2SliderValueChanged(app, event)
            value = app.Dimension2Slider.Value;
            
            app.dim2 = value;

            app.Dimension2EditField.Value = app.dim2;

            app.upDateAreaFeild();
        end

        % Value changed function: Dimension1Slider
        function Dimension1SliderValueChanged(app, event)
            value = app.Dimension1Slider.Value;
            
            app.dim1 = value;
            app.Dimension1EditField.Value = app.dim1;

            app.upDateAreaFeild();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Shape')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.ShapeButtonGroup.Title = 'Shape';
            app.RectangleButton = uiradiobutton(app.ShapeButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.RectangleButton.Value = true;
            app.CircleButton = uiradiobutton(app.ShapeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.TriangleButton = uiradiobutton(app.ShapeButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.EqualaterialTriangleButton = uiradiobutton(app.ShapeButtonGroup);
            app.EqualaterialTriangleButton.Text = 'Equalaterial Triangle';
            app.SquareButton_2 = uiradiobutton(app.ShapeButtonGroup);
            app.SquareButton_2.Text = 'Square';
            app.Dimension1EditFieldLabel = uilabel(app.UIFigure);
            app.Dimension1EditFieldLabel.Text = 'Dimension 1';
            app.Dimension1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Dimension1EditField.Value = 1;
            app.Dimension2EditFieldLabel = uilabel(app.UIFigure);
            app.Dimension2EditFieldLabel.Text = 'Dimension 2';
            app.Dimension2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Dimension2EditField.Value = 1;
            app.ColorButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorButtonGroup.Title = 'Color';
            app.RedButton = uiradiobutton(app.ColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.ColorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.ColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.YellowButton = uiradiobutton(app.ColorButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.BlackButton_2 = uiradiobutton(app.ColorButtonGroup);
            app.BlackButton_2.Text = 'Black';
            app.ShapeCreatorLabel = uilabel(app.UIFigure);
            app.ShapeCreatorLabel.Interpreter = 'tex';
            app.ShapeCreatorLabel.Text = 'Shape Creator';
            app.AreaEditFieldLabel = uilabel(app.UIFigure);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreaEditField.Value = 1;
            app.Dimension1SliderLabel = uilabel(app.UIFigure);
            app.Dimension1SliderLabel.Text = 'Dimension 1';
            app.Dimension1Slider = uislider(app.UIFigure);
            app.Dimension1Slider.ValueChangedFcn = createCallbackFcn(app, @Dimension1SliderValueChanged, true);
            app.Dimension1Slider.Value = 1;
            app.Dimension2SliderLabel = uilabel(app.UIFigure);
            app.Dimension2SliderLabel.Text = 'Dimension 2';
            app.Dimension2Slider = uislider(app.UIFigure);
            app.Dimension2Slider.ValueChangedFcn = createCallbackFcn(app, @Dimension2SliderValueChanged, true);
            app.Dimension2Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end